classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NghiemTab                       matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        NhpphngtrnhhmlpEditFieldLabel   matlab.ui.control.Label
        nhappt                          matlab.ui.control.EditField
        NhpkhongphnlynghimabEditFieldLabel  matlab.ui.control.Label
        khoang                          matlab.ui.control.EditField
        NhpsaischophpEditFieldLabel     matlab.ui.control.Label
        saiso                           matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel             matlab.ui.control.Label
        sellect                         matlab.ui.container.ButtonGroup
        chiadoi                         matlab.ui.control.RadioButton
        lap                             matlab.ui.control.RadioButton
        tieptuyen                       matlab.ui.control.RadioButton
        solanlap                        matlab.ui.control.NumericEditField
        nhan                            matlab.ui.control.StateButton
        KtquEditFieldLabel              matlab.ui.control.Label
        ketqua                          matlab.ui.control.EditField
        NoisuyTab                       matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        NhpdliuxLabel_4                 matlab.ui.control.Label
        Nhapdulieux                     matlab.ui.control.NumericEditField
        NhpdliuyLabel_4                 matlab.ui.control.Label
        Nhapdulieuy                     matlab.ui.control.NumericEditField
        ChonphuongphapnoisuyButton      matlab.ui.container.ButtonGroup
        NewtonButton                    matlab.ui.control.ToggleButton
        LagrangeButton                  matlab.ui.control.ToggleButton
        KtquathcnisuyLabel              matlab.ui.control.Label
        Ketquadathucnoisu               matlab.ui.control.EditField
        NhpgitrcnnisuyLabel             matlab.ui.control.Label
        Nhapgiatricannoisuy             matlab.ui.control.NumericEditField
        KtquButton                      matlab.ui.control.Button
        HoiquyTab                       matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        NhpgitrcndonLabel               matlab.ui.control.Label
        Ydata_DaoHam_3                  matlab.ui.control.NumericEditField
        NhpdliuyEditFieldLabel          matlab.ui.control.Label
        NhpdliuyEditField               matlab.ui.control.NumericEditField
        NhpdliuxEditFieldLabel          matlab.ui.control.Label
        NhpdliuxEditField               matlab.ui.control.NumericEditField
        ChnphngphphiquyButtonGroup      matlab.ui.container.ButtonGroup
        TuyntnhButton                   matlab.ui.control.ToggleButton
        HmmButton                       matlab.ui.control.ToggleButton
        LogaritButton                   matlab.ui.control.ToggleButton
        KtquphngtrnhhiquyLabel          matlab.ui.control.Label
        Ketquadathucnoisu_2             matlab.ui.control.EditField
        KtqudonLabel                    matlab.ui.control.Label
        Ketquadathucnoisu_3             matlab.ui.control.EditField
        DaohamTab                       matlab.ui.container.Tab
        bonustext_2                     matlab.ui.control.Label
        NhphmsEditFieldLabel_2          matlab.ui.control.Label
        Hamso_DaoHam                    matlab.ui.control.EditField
        NhpbchLabel                     matlab.ui.control.Label
        Buoch_DaoHam                    matlab.ui.control.EditField
        ChngitrsaisOhhocOh2Label        matlab.ui.control.Label
        SaiSo_DaoHam                    matlab.ui.control.EditField
        ChnphngphpButtonGroup           matlab.ui.container.ButtonGroup
        Tien_Button_DaoHam              matlab.ui.control.RadioButton
        Lui_Button_DaoHam               matlab.ui.control.RadioButton
        TrungTam_Button_DaoHam          matlab.ui.control.RadioButton
        KtquLabel                       matlab.ui.control.Label
        Ketqua_Label_DaoHam             matlab.ui.control.Label
        Tinh_Button_DaoHam              matlab.ui.control.Button
        NhpxEditFieldLabel              matlab.ui.control.Label
        XData_DaoHam                    matlab.ui.control.EditField
        NhpyEditFieldLabel              matlab.ui.control.Label
        YData_DaoHam                    matlab.ui.control.EditField
        NhpgitrcntnhohmEditFieldLabel   matlab.ui.control.Label
        Giatricantinh_EditField_DaoHam  matlab.ui.control.EditField
        TichPhanTab                     matlab.ui.container.Tab
        NhphmsEditFieldLabel            matlab.ui.control.Label
        Hamso                           matlab.ui.control.EditField
        NhpNLabel                       matlab.ui.control.Label
        dataN                           matlab.ui.control.NumericEditField
        CntrnbEditFieldLabel            matlab.ui.control.Label
        CanB                            matlab.ui.control.NumericEditField
        CndiaEditFieldLabel             matlab.ui.control.Label
        CanA                            matlab.ui.control.NumericEditField
        Methods                         matlab.ui.container.ButtonGroup
        HinhThang                       matlab.ui.control.RadioButton
        Simpson13                       matlab.ui.control.RadioButton
        Simpson38                       matlab.ui.control.RadioButton
        Calc                            matlab.ui.control.Button
        ChonInput                       matlab.ui.container.ButtonGroup
        VectorX_VectorY                 matlab.ui.control.RadioButton
        NhapHamSo                       matlab.ui.control.RadioButton
        KetQua                          matlab.ui.control.Label
        KetQuaLabel                     matlab.ui.control.Label
        NhpdliuXEditFieldLabel          matlab.ui.control.Label
        Xdata                           matlab.ui.control.EditField
        NhpdliuYEditFieldLabel          matlab.ui.control.Label
        Ydata                           matlab.ui.control.EditField
        IntroTab                        matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        THANHVIEN                       matlab.ui.container.TreeNode
        DinhDungNode                    matlab.ui.container.TreeNode
        VanHyNode                       matlab.ui.container.TreeNode
        QuangDungNode                   matlab.ui.container.TreeNode
        NguynHuyDuNode                  matlab.ui.container.TreeNode
        BaoQuocNode                     matlab.ui.container.TreeNode
        MinhQuangNode                   matlab.ui.container.TreeNode
        GIITHIUNHMLabel                 matlab.ui.control.Label
        MSSVLabel                       matlab.ui.control.Label
        MSSV                            matlab.ui.control.EditField
        NHIMVEditFieldLabel             matlab.ui.control.Label
        NHIEMVU                         matlab.ui.control.EditField
        NGYSINHEditFieldLabel           matlab.ui.control.Label
        NGAYSINH                        matlab.ui.control.EditField
        CHCVEditFieldLabel              matlab.ui.control.Label
        CHUCVU                          matlab.ui.control.EditField
        LPEditFieldLabel                matlab.ui.control.Label
        LOP                             matlab.ui.control.EditField
    end

    
    methods (Access = private)
        %Tich phan tab 
        
             % Hinh Thang function
             function y = hinhThang(app,fx,a,b,N)
%                         syms x;
%                         fx = str2func(['@(x)', fx]);
                        h = (b -a)/N;
                        s1 = (fx(a) + fx(b));
                        s2 = 0;
                        for i = 1:N-1
                            s2 = s2 + fx(a + i*h);
                        end
                        y = h/2*(s1 + 2*s2);
    
             end
            
             % Simpson13 function
            function y = tichphanSimpson13(app,fx,a,b,N)
%                         syms x;
%                         fx = str2func(['@(x)', fx]);
                        h = (b - a)/N;
                        s1 = (fx(a) + fx(b));
                        s2 = 0; 
                        s3 = 0;
                        for i=1:2:N-1
                            s2 = s2 + fx(a + i*h);
                        end
                        
                        for i=2:2:N-1
                            s3 = s3 + fx(a + i*h);
                        end
                        y = h/3*(s1 + 4*s2 + 2*s3);
            end
                  
    
            % A function to erase '.' character in fx
            function results = Change_Fx(app, fx)
                   results = strrep(fx,'*','.*');
                   results = strrep(results,'^','.^');
                   results = strrep(results,'/','./');
                
            end
      
        
        function result = Lagrange(app,xa, ya, x)
            n = length(xa);
            sum = 0;
            for i = 1:n
                product = ya(i);
                for j = 1:n
                    if i ~= j
                        product = product*(x - xa(j))/(xa(i) - xa(j));
                    end
                end
                sum = sum + product;
            end
            result = sum;
        end


        function f = Dathuc_Lagrange(app,xa, ya)
            syms x;
            f(x) = simplify(Lagrange(app,xa, ya, x));
        end

 
        % Simpson38 function
        function y = tichphanSimpson38(app,fx,a,b,N)
%              syms x;
%              fx = str2func(['@(x)', fx]);
             h = (b - a)/N;
             s1 = (fx(a) + fx(b));
             s2 = 0; 
             s3 = 0;
             for i=1:N-1
                if(rem(i, 3) == 0)
                    s3 = s3 + fx(a + i*h);
                else
                    s2 = s2 + fx(a + i*h);
                end
             end
            y = (3 * h / 8) * (s1 + 3 * s2 + 2 * s3);
           
         end
        %Tich phan tab
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ChiadoiButtonPushed(app, event)
                syms x;
                f = str2func(['@(x)',app.NhapPT.Value]);
                app.Solanlap.Value = 0;    %khai báo biến đếm số lần lặp
                while(1)
                    app.Solanlap.Value = app.Solanlap.Value + 1;    %mỗi lần lặp lại biến solanlap +1
                    c = (app.a.Value + app.b.Value) / 2;              %tính c bằng (a+b)/2
                    if(f(c) * f(app.a.Value) < 0)         %nếu f(c)*f(a)<0 có nghĩa khoảng (a,c) có nghiệm
                        app.b.Value = c;                %thay b=c
                    else
                        app.a.Value = c;                %ngược lại nếu f(c)*f(a)>0 tức trong khoảng (a,c) không có nghiệm, nghiệm nằm trong khoảng (b,c) 
                    end
                    e = abs(app.b.Value - app.a.Value);             %tính trị tuyệt đối b-1
                    if(e <= app.saiso.Value)             %nếu e< sai số thì thoát lặp
                        break
                    end
                end
                app.Ketqua.Value = app.a.Value;               %nghiệm của phương trình bắng a

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
           
            if(selectedNodes==app.MinhQuangNode)
                app.MSSV.Value = '21200339';
                app.NGAYSINH.Value = '02/01/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Giới thiệu nhóm';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end

            if (selectedNodes==app.BaoQuocNode)
                app.MSSV.Value = '21200344';
                app.NGAYSINH.Value = '10/10/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Tích phân';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end

            if (selectedNodes==app.NguynHuyDuNode)
                app.MSSV.Value = '21200279';
                app.NGAYSINH.Value = '20/08/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Đạo hàm';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thư ký';
            end
            if (selectedNodes==app.QuangDungNode)
                app.MSSV.Value = '21200282';
                app.NGAYSINH.Value = '18/12/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Hồi quy';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end
            if (selectedNodes==app.VanHyNode)
                app.MSSV.Value = '21200296';
                app.NGAYSINH.Value = '03/11/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Nội suy';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end
            if (selectedNodes==app.DinhDungNode)
                app.MSSV.Value = '21200280';
                app.NGAYSINH.Value = '16/06/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Tìm nghiệm';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Trưởng nhóm';
            end 
        end

        % Value changed function: MSSV
        function MSSVValueChanged(app, event)
            value = app.MSSV.Value;
            
        end

        % Value changed function: NGAYSINH
        function NGAYSINHValueChanged(app, event)
            value = app.NGAYSINH.Value;
            
        end

        % Value changed function: LOP
        function LOPValueChanged(app, event)
            value = app.LOP.Value;
            
        end

        % Value changed function: CHUCVU
        function CHUCVUValueChanged(app, event)
            value = app.CHUCVU.Value;
            
        end

        % Value changed function: NHIEMVU
        function NHIEMVUValueChanged(app, event)
            value = app.NHIEMVU.Value;
            
        end

        % Button pushed function: Calc
        function CalcButtonPushed(app, event)
                
                a = app.CanA.Value;
                b = app.CanB.Value;
                N = app.dataN.Value;   
                fx = app.Hamso.Value;
                fx_new = Change_Fx(app,fx);
                syms x;
                fx_hand_new = str2func(['@(x)', fx_new]);
                
                ArrayX = str2num(app.Xdata.Value);
                ArrayY = str2num(app.Ydata.Value);
                fxFromArray = Dathuc_Lagrange(app,ArrayX, ArrayY);
                fx_handle = matlabFunction(fxFromArray);
%                 fx_new1 = func2str(fx_handle);
%                 fx_hand_new1 = Change_Fx(fx_new1);
%                  
                
               if(app.HinhThang.Value == 1 && app.NhapHamSo.Value == 1)
             
                    HTRes = hinhThang(app,fx_hand_new, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', HTRes);
                    
               elseif(app.Simpson13.Value == 1 && app.NhapHamSo.Value == 1)
                   
                     SS13Res = tichphanSimpson13(app,fx_hand_new, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', SS13Res);
               elseif(app.Simpson38.Value == 1 && app.NhapHamSo.Value == 1)
                   SS38Res = tichphanSimpson38(app,fx_hand_new, a, b, N);
                   app.KetQua.Text = sprintf('%.10f', SS38Res);
           
               end
               
               if(app.HinhThang.Value == 1 && app.VectorX_VectorY.Value == 1)
             
                    HTRes = hinhThang(app,fx_handle, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', HTRes);
                    
               elseif(app.Simpson13.Value == 1 && app.VectorX_VectorY.Value == 1)
                   
                     SS13Res = tichphanSimpson13(app,fx_handle, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', SS13Res);
               elseif(app.Simpson38.Value == 1 && app.VectorX_VectorY.Value == 1)
                   SS38Res = tichphanSimpson38(app,fx_handle, a, b, N);
                   app.KetQua.Text = sprintf('%.10f', SS38Res);
           
               end
        end

        % Selection changed function: ChonInput
        function ChonInputSelectionChanged(app, event)
            selectedButton = app.ChonInput.SelectedObject;
            if(selectedButton == app.VectorX_VectorY)
                app.Hamso.Visible = "off";
                app.Ydata.Visible = "on";
                app.Xdata.Visible = "on";
            else
                app.Hamso.Visible = "on";
                app.Ydata.Visible = "off";
                app.Xdata.Visible = "off";
            end
        end

        % Button pushed function: Tinh_Button_DaoHam
        function Tinh_Button_DaoHamPushed(app, event)
            x = str2num(app.XData_DaoHam.Value);
            y = str2num(app.YData_DaoHam.Value);
            x_val = str2num(app.Giatricantinh_EditField_DaoHam.Value);

            if app.TrungTam_Button_DaoHam.Value == 1
                method = 'central';
            end

            if app.Lui_Button_DaoHam.Value == 1
                method = 'backward';
            end

            if app.Tien_Button_DaoHam.Value == 1
                method = 'forward';
            end

            df = approximate_derivative_at_point(x,y,x_val,method);
            app.Ketqua_Label_DaoHam.Text = num2str(df);
        end

        % Value changed function: nhan
        function nhanValueChanged(app, event)
            syms x;
            f = str2func(['@(x)',app.nhappt.Value]);
            khoangtinh = app.khoang.Value; 
            khoangtinh = regexprep(khoangtinh, '[\[\]]', ''); % loại bỏ các ký tự [ và ]
            giatri = strsplit(khoangtinh, ';'); % tách chuỗi thành hai phần tử dựa trên dấu ;
            a = str2double(giatri{1}); % chuyển đổi chuỗi sang số
            b = str2double(giatri{2}); % chuyển đổi chuỗi sang số
            saiso=app.saiso.Value;
            solanlap_max=2000;
            x1=linspace(a,b,1000);
            y=arrayfun(f,x1)%tính toán giá trị hàm f cho mỗi giá trị x1
            plot(app.UIAxes3,x1,y);
            if(app.chiadoi.Value==1)
                 if f(a) * f(b) > 0
                    app.ketqua.Value='Kiểm tra lại khoảng phân ly, sao cho có 1 nghiệm đơn';
                 return
                 end
                 app.solanlap.Value=0;
                 while (b-a)/2 > saiso
                        if(app.solanlap.Value>solanlap_max)
                            return
                        end
                        c = (a+b)/2;
                        if f(c)==0
                            break
                        elseif f(a)*f(c)<0
                            b=c;
                        else
                            a=c;
                        end
                        app.solanlap.Value=app.solanlap.Value+ 1;
                 end
                 app.ketqua.Value=num2str((a+b)/2);
            end
            if(app.tieptuyen.Value==1)
            fd1 = str2func(['@(x)' ,char(diff(f(x)))]);
            fd2 = str2func(['@(x)' ,char(diff(diff(f(x))))]);
            app.solanlap.Value=0;         %khai báo biến đếm số lần lặp
            x0=a;
            nostop=1
            if f(a) * f(b) > 0
                  app.ketqua.Value='Kiểm tra lại khoảng phân ly, sao cho có 1 nghiệm đơn';
                  return
            end
            if(nostop)
            while f(x0)*fd2(x0)<0    
                x0 = (x0 + b) / 2;
            end 
            app.solanlap.Value = 0;
            while (1)
                if(app.solanlap.Value>solanlap_max)
                   return
                end
                app.solanlap.Value = app.solanlap.Value + 1;
                x1 = x0 - f(x0)/fd1(x0);
            if(abs(x1-x0)<app.saiso.Value)
                break
            end
            x0=x1;
            end
            app.ketqua.Value = num2str(x1);
            end
            end
            if(app.lap.Value==1)
            app.solanlap.Value=0;     %khai báo biến đếm số lần lặp 
            i=2;            %thiết lập giá trị ban đầu của i=2
            z(1)=a;         %gán x(1)=a 
            z(2)=b;         %gán x(2)=b
            while(1)
                if(app.solanlap.Value>solanlap_max)
                   return
                end
                app.solanlap.Value=app.solanlap.Value+1;    %biến lặp tăng 1 sau mỗi vòng lặp
                z(i)=f(z(i-1));        %tính giá trị x(i) theo hàm lặp với giá trị x(i-1) trước đó
                if abs(z(i)-z(i-1))<=app.saiso.Value       %hiệu tuyệt đối giua gia trị hiện tại và giá trị trước đó nếu nhỏ hơn sai số thì thoát lặp 
                break;
                end
                i=i+1;    %tăng i để tính x(i) mới       
            end
            app.ketqua.Value=num2str(z(i));  %gán nghiệm chính là x(i)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes3 = uiaxes(app.NghiemTab);
            title(app.UIAxes3, 'Đồ thị hàm số')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.NhpphngtrnhhmlpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhhmlpEditFieldLabel.Text = 'Nhập phương trình / hàm lặp';
            app.nhappt = uieditfield(app.NghiemTab, 'text');
            app.nhappt.Value = 'x-2';
            app.NhpkhongphnlynghimabEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimabEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm [a;b]';
            app.khoang = uieditfield(app.NghiemTab, 'text');
            app.khoang.Value = '[1;3]';
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.saiso = uieditfield(app.NghiemTab, 'numeric');
            app.saiso.Value = 0.001;
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.sellect = uibuttongroup(app.NghiemTab);
            app.sellect.Title = 'Chọn phương pháp tìm nghiệm';
            app.chiadoi = uiradiobutton(app.sellect);
            app.chiadoi.Text = 'Phương pháp chia đôi';
            app.chiadoi.Value = true;
            app.lap = uiradiobutton(app.sellect);
            app.lap.Text = 'Phương pháp lặp';
            app.tieptuyen = uiradiobutton(app.sellect);
            app.tieptuyen.Text = 'Phương pháp Newton (Tiếp tuyến)';
            app.solanlap = uieditfield(app.NghiemTab, 'numeric');
            app.nhan = uibutton(app.NghiemTab, 'state');
            app.nhan.ValueChangedFcn = createCallbackFcn(app, @nhanValueChanged, true);
            app.nhan.Text = 'Tính và Vẽ';
            app.KtquEditFieldLabel = uilabel(app.NghiemTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.ketqua = uieditfield(app.NghiemTab, 'text');
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội Suy';
            app.UIAxes_2 = uiaxes(app.NoisuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số nội suy và dữ liệu')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.NhpdliuxLabel_4 = uilabel(app.NoisuyTab);
            app.NhpdliuxLabel_4.Text = 'Nhập dữ liệu x';
            app.Nhapdulieux = uieditfield(app.NoisuyTab, 'numeric');
            app.NhpdliuyLabel_4 = uilabel(app.NoisuyTab);
            app.NhpdliuyLabel_4.Text = 'Nhập dữ liệu y';
            app.Nhapdulieuy = uieditfield(app.NoisuyTab, 'numeric');
            app.ChonphuongphapnoisuyButton = uibuttongroup(app.NoisuyTab);
            app.ChonphuongphapnoisuyButton.Title = 'Chọn phương pháp nội suy';
            app.NewtonButton = uitogglebutton(app.ChonphuongphapnoisuyButton);
            app.NewtonButton.Text = 'Newton';
            app.NewtonButton.Value = true;
            app.LagrangeButton = uitogglebutton(app.ChonphuongphapnoisuyButton);
            app.LagrangeButton.Text = 'Lagrange';
            app.KtquathcnisuyLabel = uilabel(app.NoisuyTab);
            app.KtquathcnisuyLabel.Text = 'Kết quả đa thức nội suy';
            app.Ketquadathucnoisu = uieditfield(app.NoisuyTab, 'text');
            app.NhpgitrcnnisuyLabel = uilabel(app.NoisuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy';
            app.Nhapgiatricannoisuy = uieditfield(app.NoisuyTab, 'numeric');
            app.KtquButton = uibutton(app.NoisuyTab, 'push');
            app.KtquButton.Text = 'Kết quả';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi Quy';
            app.UIAxes2 = uiaxes(app.HoiquyTab);
            title(app.UIAxes2, ' Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.NhpgitrcndonLabel = uilabel(app.HoiquyTab);
            app.NhpgitrcndonLabel.Text = 'Nhập giá trị cần dự đoán';
            app.Ydata_DaoHam_3 = uieditfield(app.HoiquyTab, 'numeric');
            app.NhpdliuyEditFieldLabel = uilabel(app.HoiquyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.NhpdliuxEditFieldLabel = uilabel(app.HoiquyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.ChnphngphphiquyButtonGroup = uibuttongroup(app.HoiquyTab);
            app.ChnphngphphiquyButtonGroup.Title = 'Chọn phương pháp hồi quy';
            app.TuyntnhButton = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.TuyntnhButton.Text = 'Tuyến tính';
            app.TuyntnhButton.Value = true;
            app.HmmButton = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.HmmButton.Text = 'Hàm mũ';
            app.LogaritButton = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.LogaritButton.Text = 'Logarit';
            app.KtquphngtrnhhiquyLabel = uilabel(app.HoiquyTab);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy';
            app.Ketquadathucnoisu_2 = uieditfield(app.HoiquyTab, 'text');
            app.KtqudonLabel = uilabel(app.HoiquyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.Ketquadathucnoisu_3 = uieditfield(app.HoiquyTab, 'text');
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo Hàm';
            app.bonustext_2 = uilabel(app.DaohamTab);
            app.bonustext_2.Text = 'Hoặc';
            app.NhphmsEditFieldLabel_2 = uilabel(app.DaohamTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Hamso_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.NhpbchLabel = uilabel(app.DaohamTab);
            app.NhpbchLabel.Text = 'Nhập bước h ';
            app.Buoch_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.ChngitrsaisOhhocOh2Label = uilabel(app.DaohamTab);
            app.ChngitrsaisOhhocOh2Label.Text = {'Chọn giá trị sai số '; 'O(h) hoặc O(h^2)'};
            app.SaiSo_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.ChnphngphpButtonGroup = uibuttongroup(app.DaohamTab);
            app.ChnphngphpButtonGroup.Title = 'Chọn phương pháp ';
            app.Tien_Button_DaoHam = uiradiobutton(app.ChnphngphpButtonGroup);
            app.Tien_Button_DaoHam.Text = 'Xấp xỉ tiến ';
            app.Tien_Button_DaoHam.Value = true;
            app.Lui_Button_DaoHam = uiradiobutton(app.ChnphngphpButtonGroup);
            app.Lui_Button_DaoHam.Text = 'Xấp xỉ lùi ';
            app.TrungTam_Button_DaoHam = uiradiobutton(app.ChnphngphpButtonGroup);
            app.TrungTam_Button_DaoHam.Text = 'Xấp xỉ trung tâm ';
            app.KtquLabel = uilabel(app.DaohamTab);
            app.KtquLabel.Text = 'Kết quả ';
            app.Ketqua_Label_DaoHam = uilabel(app.DaohamTab);
            app.Ketqua_Label_DaoHam.Text = '0';
            app.Tinh_Button_DaoHam = uibutton(app.DaohamTab, 'push');
            app.Tinh_Button_DaoHam.ButtonPushedFcn = createCallbackFcn(app, @Tinh_Button_DaoHamPushed, true);
            app.Tinh_Button_DaoHam.Text = 'Tính ';
            app.NhpxEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpxEditFieldLabel.Text = 'Nhập x ';
            app.XData_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.XData_DaoHam.Value = '[0.1 0.3 0.5 0.7 0.9]';
            app.NhpyEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpyEditFieldLabel.Text = 'Nhập y ';
            app.YData_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.YData_DaoHam.Value = '[0.1002 0.3047 0.5236 0.7754 1.1198]';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính Đạo hàm ';
            app.Giatricantinh_EditField_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích Phân';
            app.NhphmsEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Hamso = uieditfield(app.TichPhanTab, 'text');
            app.Hamso.Value = 'x^3*sin(x) + x*cos(x)';
            app.NhpNLabel = uilabel(app.TichPhanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.dataN = uieditfield(app.TichPhanTab, 'numeric');
            app.dataN.Value = 10;
            app.CntrnbEditFieldLabel = uilabel(app.TichPhanTab);
            app.CntrnbEditFieldLabel.Text = 'Cận trên (b)';
            app.CanB = uieditfield(app.TichPhanTab, 'numeric');
            app.CanB.Value = 2;
            app.CndiaEditFieldLabel = uilabel(app.TichPhanTab);
            app.CndiaEditFieldLabel.Text = 'Cận dưới (a)';
            app.CanA = uieditfield(app.TichPhanTab, 'numeric');
            app.Methods = uibuttongroup(app.TichPhanTab);
            app.Methods.Title = 'Chọn phương pháp tính tích phân';
            app.HinhThang = uiradiobutton(app.Methods);
            app.HinhThang.Text = 'Hình Thang';
            app.HinhThang.Value = true;
            app.Simpson13 = uiradiobutton(app.Methods);
            app.Simpson13.Text = 'Simpson 1/3';
            app.Simpson38 = uiradiobutton(app.Methods);
            app.Simpson38.Text = 'Simpson 3/8';
            app.Calc = uibutton(app.TichPhanTab, 'push');
            app.Calc.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.Calc.Text = 'Tính ';
            app.ChonInput = uibuttongroup(app.TichPhanTab);
            app.ChonInput.SelectionChangedFcn = createCallbackFcn(app, @ChonInputSelectionChanged, true);
            app.ChonInput.Title = 'Chọn dữ liệu đầu vào';
            app.VectorX_VectorY = uiradiobutton(app.ChonInput);
            app.VectorX_VectorY.Text = 'Mảng x, Mảng y';
            app.VectorX_VectorY.Value = true;
            app.NhapHamSo = uiradiobutton(app.ChonInput);
            app.NhapHamSo.Text = 'Hàm số';
            app.KetQua = uilabel(app.TichPhanTab);
            app.KetQua.Text = '0';
            app.KetQuaLabel = uilabel(app.TichPhanTab);
            app.KetQuaLabel.Text = 'Kết Quả';
            app.NhpdliuXEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpdliuXEditFieldLabel.Text = 'Nhập dữ liệu X';
            app.Xdata = uieditfield(app.TichPhanTab, 'text');
            app.Xdata.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuYEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpdliuYEditFieldLabel.Text = 'Nhập dữ liệu Y';
            app.Ydata = uieditfield(app.TichPhanTab, 'text');
            app.Ydata.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Giới thiệu nhóm';
            app.Tree = uitree(app.IntroTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.THANHVIEN = uitreenode(app.Tree);
            app.THANHVIEN.Text = '   THÀNH VIÊN';
            app.DinhDungNode = uitreenode(app.THANHVIEN);
            app.DinhDungNode.Text = 'Lê Đình Dũng';
            app.VanHyNode = uitreenode(app.THANHVIEN);
            app.VanHyNode.Text = 'Đặng Văn Hy';
            app.QuangDungNode = uitreenode(app.THANHVIEN);
            app.QuangDungNode.Text = 'Phạm Quang Dũng';
            app.NguynHuyDuNode = uitreenode(app.THANHVIEN);
            app.NguynHuyDuNode.Text = 'Nguyễn Huy Du';
            app.BaoQuocNode = uitreenode(app.THANHVIEN);
            app.BaoQuocNode.Text = 'Phạm Bảo Quốc';
            app.MinhQuangNode = uitreenode(app.THANHVIEN);
            app.MinhQuangNode.Text = 'Đỗ Minh Quang';
            app.GIITHIUNHMLabel = uilabel(app.IntroTab);
            app.GIITHIUNHMLabel.Text = 'GIỚI THIỆU NHÓM';
            app.MSSVLabel = uilabel(app.IntroTab);
            app.MSSVLabel.Text = 'MSSV ';
            app.MSSV = uieditfield(app.IntroTab, 'text');
            app.MSSV.ValueChangedFcn = createCallbackFcn(app, @MSSVValueChanged, true);
            app.MSSV.Editable = 'off';
            app.NHIMVEditFieldLabel = uilabel(app.IntroTab);
            app.NHIMVEditFieldLabel.Text = 'NHIỆM VỤ';
            app.NHIEMVU = uieditfield(app.IntroTab, 'text');
            app.NHIEMVU.ValueChangedFcn = createCallbackFcn(app, @NHIEMVUValueChanged, true);
            app.NHIEMVU.Editable = 'off';
            app.NGYSINHEditFieldLabel = uilabel(app.IntroTab);
            app.NGYSINHEditFieldLabel.Text = 'NGÀY SINH';
            app.NGAYSINH = uieditfield(app.IntroTab, 'text');
            app.NGAYSINH.ValueChangedFcn = createCallbackFcn(app, @NGAYSINHValueChanged, true);
            app.NGAYSINH.Editable = 'off';
            app.CHCVEditFieldLabel = uilabel(app.IntroTab);
            app.CHCVEditFieldLabel.Text = 'CHỨC VỤ';
            app.CHUCVU = uieditfield(app.IntroTab, 'text');
            app.CHUCVU.ValueChangedFcn = createCallbackFcn(app, @CHUCVUValueChanged, true);
            app.CHUCVU.Editable = 'off';
            app.LPEditFieldLabel = uilabel(app.IntroTab);
            app.LPEditFieldLabel.Text = 'LỚP';
            app.LOP = uieditfield(app.IntroTab, 'text');
            app.LOP.ValueChangedFcn = createCallbackFcn(app, @LOPValueChanged, true);
            app.LOP.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end